classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditFieldTextArea       matlab.ui.control.TextArea
        EditFieldTextAreaLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        ControlPanel            matlab.ui.container.Panel
        PageIndicatorField      matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        EarliestButton          matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        LatestButton            matlab.ui.control.Button
        OutputButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        ModeSlider              matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        ColorModeDropDown       matlab.ui.control.DropDown
        ColorModeLabel          matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Button                  matlab.ui.control.Button
        DifferenceButton        matlab.ui.control.Button
        TimelapseButton         matlab.ui.control.Button
        OverlayButton           matlab.ui.control.Button
        SliderButton            matlab.ui.control.Button
        EarliestLatestButton    matlab.ui.control.Button
        MenuPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        AnalyzeButton           matlab.ui.control.Button
        LatestDropDown          matlab.ui.control.DropDown
        LatestDropDownLabel     matlab.ui.control.Label
        EarliestDropDown        matlab.ui.control.DropDown
        EarliestDropDownLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFolder          % Folder path selected by user
        ImageList            % Sorted list of image filenames
        AlignedImages        % Aligned/preprocessed image data
        TransformParams      % Registration parameters from preprocessing
        idxEarliest          % Index of the earliest selected image
        idxLatest            % Index of the latest selected image
        RGBButton
        GrayButton
        CurrentMode          char    
        FolderPathEditField  matlab.ui.control.EditField
        currentIndex = 1
        imgEarliest
        imgLatest
        EarliestLatestSubset     % Subset of images for paging
    end

    
   methods (Access = private) 

    function showModeControls(app, mode)
        % 先把所有模式控件都隐藏
        % =========================
        app.ColorModeDropDown.Visible = 'off';
        app.ModeSlider.Visible = 'off';
        app.PlayButton.Visible = 'off';

        app.EarliestButton.Visible = 'off';
        app.PreviousButton.Visible = 'off';
        app.NextButton.Visible = 'off';
        app.LatestButton.Visible = 'off';
        app.PageIndicatorField.Visible = 'off';


        % 根据当前模式显示需要的
        % =========================
        switch mode
            case 'Difference'
                app.ColorModeDropDown.Visible = 'on';

            case 'Slider'
                app.ModeSlider.Visible = 'on';
                % 缓存起始和结束图像
                app.imgEarliest = app.AlignedImages{app.idxEarliest};
                app.imgLatest = app.AlignedImages{app.idxLatest};

                % 设置slider到中间
                app.ModeSlider.Value = 50;

                % 初始显示
                app.updateSliderBlend();
                
            case 'Overlay'
                app.ModeSlider.Visible = 'on';

            case 'Time-lapse'
                if numel(app.AlignedImages) < 10
                    uialert(app.UIFigure, ...
                        'Need at least 10 images for time-lapse.', 'Error');
                else
                    app.PlayButton.Visible = 'on';
                end

            case 'EarliestLatest'
                % 📌 显示阅读器用的翻页按钮和文本框
                app.EarliestButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.LatestButton.Visible = 'on';
                app.PageIndicatorField.Visible = 'on';
                app.currentIndex = 1;
                app.updateImageDisplay();
        end

    end

    function updateImageDisplay(app)
        if strcmp(app.CurrentMode, 'EarliestLatest')
            if isempty(app.EarliestLatestSubset)
                cla(app.UIAxes);
                app.PageIndicatorField.Value = '0/0';
                return;
            end
            total = numel(app.EarliestLatestSubset);
        else
            total = numel(app.AlignedImages);
        end

        % 防止越界
        if app.currentIndex < 1
            app.currentIndex = 1;
        elseif app.currentIndex > total
            app.currentIndex = total;
        end

        % 显示图像
        if strcmp(app.CurrentMode, 'EarliestLatest')
            imshow(app.EarliestLatestSubset{app.currentIndex}, 'Parent', app.UIAxes);
        else
            imshow(app.AlignedImages{app.currentIndex}, 'Parent', app.UIAxes);
        end
        axis(app.UIAxes, 'off');

        % 更新文本
        app.PageIndicatorField.Value = sprintf('%d / %d', app.currentIndex, total);

    end
    function updateSliderBlend(app)
        % 获取滑块值（0到100）
        t = app.ModeSlider.Value / 100;

        % 做线性混合
        img1 = im2double(app.imgEarliest);
        img2 = im2double(app.imgLatest);

        % 确保大小一致
        if ~isequal(size(img1), size(img2))
            uialert(app.UIFigure, 'Images are not the same size.', 'Error');
            return;
        end

        blended = (1 - t) * img1 + t * img2;

        % 显示
        imshow(blended, 'Parent', app.UIAxes);
        axis(app.UIAxes, 'off');
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Ask user to select a folder
            folderPath = uigetdir;
            if folderPath == 0
                uialert(app.UIFigure, 'No folder selected.', 'Error');
                return;
            end
            app.ImageFolder = folderPath;
            app.EditFieldTextArea.Value = folderPath;
            % Get all image files in the selected folder
            imgFormats = {'*.jpg','*.jpeg','*.png','*.tif','*.bmp'};
            files = [];
            for i = 1:length(imgFormats)
                files = [files; dir(fullfile(folderPath, imgFormats{i}))];
            end

            if isempty(files)
                uialert(app.UIFigure, 'No image files found in the selected folder.', 'Error');
                return;
            end

            % Extract filenames
            fileList = {files.name};

            % Try to sort by naming format (YYYY MM)
            % try
            %     dates = zeros(length(fileList), 2);
            %     for i = 1:length(fileList)
            %         tokens = regexp(fileList{i}, '(\d{4}) (\d{2})', 'tokens');
            %         if isempty(tokens)
            %             error('Invalid naming format');
            %         end
            %         dates(i, :) = [str2double(tokens{1}{1}), str2double(tokens{1}{2})];
            %     end
            %     [~, sortIdx] = sortrows(dates);
            %     sortedList = fileList(sortIdx);
            % catch
            %     uialert(app.UIFigure, 'Invalid filename format. Expected "YYYY MM.jpg"', 'Error');
            %     return;
            % end
            sortedList = sort(fileList);

            % Store sorted list in app properties
            app.ImageList = sortedList;

            % Call the preprocessing module
            % [app.AlignedImages, app.TransformParams] = register_images(folderPath, sortedList);
            app.AlignedImages = cellfun(@(f) imread(fullfile(folderPath,f)), sortedList, 'UniformOutput', false);
            app.TransformParams = [];

            % Update dropdown menus
            app.EarliestDropDown.Items = sortedList;
            app.LatestDropDown.Items = sortedList;
            % Set default selections
            app.EarliestDropDown.Value = sortedList{1};
            app.LatestDropDown.Value = sortedList{end};

            % Record default idx
            app.idxEarliest = 1;
            app.idxLatest = numel(sortedList);

            % Feedback in command window
            disp('Images successfully imported and preprocessed:');
            disp(sortedList);
        end

        % Value changed function: EarliestDropDown
        function EarliestDropDownValueChanged(app, event)
           app.idxEarliest = find(strcmp(app.ImageList, app.EarliestDropDown.Value));
            
        end

        % Value changed function: LatestDropDown
        function LatestDropDownValueChanged(app, event)
            app.idxLatest = find(strcmp(app.ImageList, app.LatestDropDown.Value));
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Check if both earliest and latest images are selected
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both the earliest and latest images.', 'Error');
                return;
            end

            % Validate that the latest image is after the earliest
            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'The latest image must be later than the earliest image.', 'Error');
                return;
            end

            % Display the earliest image as the default view
            imshow(app.AlignedImages{app.idxEarliest}, 'Parent', app.UIAxes)
            axis(app.UIAxes, 'off')

            % TODO: Switch to the analysis/mode selection view
            % You can switch panels here if using a multi-page layout
            disp('Ready to enter the analysis view.')
            disp('AlignedImages count:')
            disp(numel(app.AlignedImages))

            disp('idxEarliest selected:')
            disp(app.idxEarliest)

        end

        % Button pushed function: DifferenceButton
        function DifferenceButtonPushed(app, event)
            app.CurrentMode = 'Difference';
            app.showModeControls('Difference');
            % % show Gray
            % app.updateDifferenceView('Gray');


        end

        % Button pushed function: EarliestLatestButton
        function EarliestLatestButtonPushed(app, event)
            app.CurrentMode = 'EarliestLatest';

          
            if app.idxEarliest < app.idxLatest
                app.EarliestLatestSubset = app.AlignedImages(app.idxEarliest : app.idxLatest);
            else
                app.EarliestLatestSubset = {};  
            end

            app.currentIndex = 1;
            app.showModeControls('EarliestLatest');

            % earliest
            % imshow(app.AlignedImages{app.idxEarliest}, 'Parent', app.UIAxes);
        end

        % Button pushed function: SliderButton
        function SliderButtonPushed(app, event)
            app.CurrentMode = 'Slider';
            app.showModeControls('Slider');
        end

        % Button pushed function: OverlayButton
        function OverlayButtonPushed(app, event)
            app.CurrentMode = 'Overlay';
            app.showModeControls('Overlay');
        end

        % Button pushed function: TimelapseButton
        function TimelapseButtonPushed(app, event)
            app.CurrentMode = 'Time-lapse';
            app.showModeControls('Time-lapse');
        end

        % Button pushed function: EarliestButton
        function EarliestButtonPushed(app, event)
            app.currentIndex = 1;
            app.updateImageDisplay();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.currentIndex > 1
                app.currentIndex = app.currentIndex - 1;
            end
            app.updateImageDisplay();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                maxCount = numel(app.EarliestLatestSubset);
            else
                maxCount = numel(app.AlignedImages);
            end

            if app.currentIndex < maxCount
                app.currentIndex = app.currentIndex + 1;
            end

            app.updateImageDisplay();
        end

        % Button pushed function: LatestButton
        function LatestButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                app.currentIndex = numel(app.EarliestLatestSubset);
            else
                app.currentIndex = numel(app.AlignedImages);
            end

            app.updateImageDisplay();
        end

        % Value changed function: ModeSlider
        function ModeSliderValueChanged(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end

        % Value changing function: ModeSlider
        function ModeSliderValueChanging(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Display')
            app.UIAxes.Tag = 'DisplayAxes';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.MenuPanel.Tag = 'Menu';
            app.GridLayout2 = uigridlayout(app.MenuPanel);
            app.EarliestDropDownLabel = uilabel(app.GridLayout2);
            app.EarliestDropDownLabel.Text = 'Earliest';
            app.EarliestDropDown = uidropdown(app.GridLayout2);
            app.EarliestDropDown.ValueChangedFcn = createCallbackFcn(app, @EarliestDropDownValueChanged, true);
            app.EarliestDropDown.Tag = 'EarliestDropDown';
            app.LatestDropDownLabel = uilabel(app.GridLayout2);
            app.LatestDropDownLabel.Text = 'Latest ';
            app.LatestDropDown = uidropdown(app.GridLayout2);
            app.LatestDropDown.ValueChangedFcn = createCallbackFcn(app, @LatestDropDownValueChanged, true);
            app.LatestDropDown.Tag = 'LatestDropDown';
            app.AnalyzeButton = uibutton(app.GridLayout2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tag = 'AnalyzeButton';
            app.AnalyzeButton.Text = 'Analyze';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.EarliestLatestButton = uibutton(app.GridLayout3, 'push');
            app.EarliestLatestButton.ButtonPushedFcn = createCallbackFcn(app, @EarliestLatestButtonPushed, true);
            app.EarliestLatestButton.Tag = 'EarliestButton';
            app.EarliestLatestButton.Text = 'Earliest/Latest';
            app.SliderButton = uibutton(app.GridLayout3, 'push');
            app.SliderButton.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonPushed, true);
            app.SliderButton.Tag = 'SliderButton';
            app.SliderButton.Text = 'Slider';
            app.OverlayButton = uibutton(app.GridLayout3, 'push');
            app.OverlayButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayButtonPushed, true);
            app.OverlayButton.Tag = 'OverlayButton';
            app.OverlayButton.Text = 'Overlay';
            app.TimelapseButton = uibutton(app.GridLayout3, 'push');
            app.TimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @TimelapseButtonPushed, true);
            app.TimelapseButton.Tag = 'TimelapseButton';
            app.TimelapseButton.Text = 'Time-lapse';
            app.DifferenceButton = uibutton(app.GridLayout3, 'push');
            app.DifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @DifferenceButtonPushed, true);
            app.DifferenceButton.Tag = 'DifferenceButton';
            app.DifferenceButton.Text = 'Difference';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.Tag = 'LatestButton';
            app.Button.Text = '';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Tag = 'ControlPanel';
            app.ColorModeLabel = uilabel(app.ControlPanel);
            app.ColorModeLabel.Visible = 'off';
            app.ColorModeLabel.Text = 'Color Mode';
            app.ColorModeDropDown = uidropdown(app.ControlPanel);
            app.ColorModeDropDown.Items = {'Gray', 'RGB', 'R', 'G', 'B'};
            app.ColorModeDropDown.Visible = 'off';
            app.ColorModeDropDown.Value = 'Gray';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '';
            app.ModeSlider = uislider(app.ControlPanel);
            app.ModeSlider.ValueChangedFcn = createCallbackFcn(app, @ModeSliderValueChanged, true);
            app.ModeSlider.ValueChangingFcn = createCallbackFcn(app, @ModeSliderValueChanging, true);
            app.ModeSlider.Visible = 'off';
            app.PlayButton = uibutton(app.ControlPanel, 'push');
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.OutputButton = uibutton(app.ControlPanel, 'push');
            app.OutputButton.Text = 'Output';
            app.LatestButton = uibutton(app.ControlPanel, 'push');
            app.LatestButton.ButtonPushedFcn = createCallbackFcn(app, @LatestButtonPushed, true);
            app.LatestButton.Visible = 'off';
            app.LatestButton.Text = 'Latest';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ControlPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.EarliestButton = uibutton(app.ControlPanel, 'push');
            app.EarliestButton.ButtonPushedFcn = createCallbackFcn(app, @EarliestButtonPushed, true);
            app.EarliestButton.Visible = 'off';
            app.EarliestButton.Text = 'Earliest';
            app.Label_2 = uilabel(app.ControlPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.PageIndicatorField = uitextarea(app.ControlPanel);
            app.PageIndicatorField.Editable = 'off';
            app.PageIndicatorField.Visible = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tag = 'ImportButton';
            app.BrowseButton.Text = 'Browse';
            app.EditFieldTextAreaLabel = uilabel(app.UIFigure);
            app.EditFieldTextAreaLabel.Text = 'Edit Field';
            app.EditFieldTextArea = uitextarea(app.UIFigure);
            app.EditFieldTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end